classdef Phase_Vocoder_GUI_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        ComputeButton           matlab.ui.control.Button
        OverlapKnobLabel        matlab.ui.control.Label
        OverlapKnob             matlab.ui.control.Knob
        WindowSliderLabel       matlab.ui.control.Label
        WindowSlider            matlab.ui.control.Slider
        StretchQKnobLabel       matlab.ui.control.Label
        StretchQKnob            matlab.ui.control.Knob
        BrowseButton            matlab.ui.control.Button
        FileNameEditFieldLabel  matlab.ui.control.Label
        FileNameEditField       matlab.ui.control.EditField
        PitchKnobLabel          matlab.ui.control.Label
        PitchKnob               matlab.ui.control.DiscreteKnob
        VariableQSwitchLabel    matlab.ui.control.Label
        VariableQSwitch         matlab.ui.control.Switch
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        PlayOriginalButton      matlab.ui.control.Button
        PlayStretchedButton     matlab.ui.control.Button
        UIAxes5                 matlab.ui.control.UIAxes
        UIAxes6                 matlab.ui.control.UIAxes
        Stop_SoundButton        matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        StatusLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        y_prop; % Description
        x_prop; % Description
        lamp_prop;
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
              
            app.Label.Text= 'Working...';
            pause(0.01)
            ppa=inline('mod(a+pi,2*pi)-pi','a'); %Function to WrapToPi
            
            %read in our WAV file, and store sample rate in Fs
            [x,Fs]=audioread(app.FileNameEditField.Value);
            x = 0.5*sum(x,2)';                  %stereo to mono
            
            %frame length, for now 50ms
            time_frame=app.WindowSlider.Value/1000;
            N = round(Fs*time_frame); 
            fft_size=ceil(log(N)/log(2));
                
            H=app.OverlapKnob.Value/100;                             %Overlap Percentage
            HA=round(N-(N*H));                  %actual overlap in bins
            
            %Stretch Coefficient {Q>1 time stretch}, {Q<1 time compress} {Q=1 same length} 
                  %output Vector

            %Defining Window (Hanning)
            q=[0:1:N-1];
            win=0.5-0.5*cos(2*pi*q/N);
            
            %Zero Padding in the start and end  
            x=[zeros(1,N),x,zeros(1,round(N-HA))];
            L= length(x);                       %Length of total input vector
            
            Nf=floor((L-N)/HA);                 %Number of frames
            r=zeros(1,L);                       %for Window interpolation
            n=1:1:N;
 
            if( app.lamp_prop== 1) 
                Q=exp(linspace(1.2,1/2,Nf))./2.73; 
            else
                Q=app.StretchQKnob.Value*ones(1,Nf); 
            end
            
            Hs=floor(Q*HA);                     %Synthesis Hop Length
            hh=sum(Q.*(2^fft_size));
            y=zeros(1,ceil(hh));  
            switch app.PitchKnob.Value
                case '1'
                   m_p=1;
                   n_p=1;
                   
                case '1.5'
                   m_p=2;
                   n_p=3;
                
                case '2'
                   m_p=1;
                   n_p=2;
                
                case '0.5'
                   m_p=2;
                   n_p=1;
                
                case '0.75'
                   m_p=4;
                   n_p=3;    
            end
            
            pitch_ratio=m_p/n_p;
            
            y=zeros(1,ceil(2*N+ sum(Hs)));  %output Vector
            pitch_c=1:1:round((m_p/n_p*N));
            y=[y,zeros(1,round(m_p/n_p*N))];    % Adding additional zeros for pitch
            
            %frequency vector for N-length DFT
            phi_m=zeros(1,2^fft_size);
            theta=zeros(1,2^fft_size);
            wmk=zeros(1,2^fft_size);
            bin_freq=2*pi*linspace(1,2^fft_size,2^fft_size)/(2^fft_size);
            
            for i=1:1:Nf-1
                fft_in=fft((x(1,n+i*HA)),2^fft_size);
                Xmag=abs(fft_in);
                Xang=angle(fft_in);
                wmk=ppa((Xang-phi_m-(bin_freq.*HA))/HA);
                theta=theta+(wmk*Hs(i))+(bin_freq.*Hs(i));
                Y=Xmag.*exp(j*theta);
                x_ifft=ifft(Y,'symmetric');
                x_ifft=x_ifft(1,1:N).*win;
                x_ifft=resample(x_ifft,m_p,n_p);
                y(1,pitch_c+(i*Hs(i)))=y(1,pitch_c+(i*Hs(i)))+((1/2)*x_ifft);
                r(1,n+(i*HA))=r(1,n+(i*HA))+win;
                phi_m=Xang;
            end
            
            %Extracting the final signals 
            x=x(1,N:L-round(2*N-HA));
            y=y(1,N:(length(y)-round(2*N-HA)));
            
            %Plotting 
            
          
            plot(app.UIAxes,x);    
            plot(app.UIAxes3,y);
    
            if Q==1              
                plot(app.UIAxes2,r);
                length(y)
                length(x)
                y=y(1:length(x));
                plot(app.UIAxes4,x-y);
            end
             MA1_s2002343_Golcha_myspec_1(app.UIAxes5,x',Fs,N,H);
             MA1_s2002343_Golcha_myspec_1(app.UIAxes6,y',Fs,N,H);
            app.y_prop=y;
            app.x_prop=x;
            app.Label.Text= 'Done';
            pause(0.01)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path]=uigetfile('*.wav');
            file_path=[path,file];
            if (file_path ~= "")
                app.FileNameEditField.Value=file_path;
            end    
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            soundsc(app.x_prop,44100);
        end

        % Button pushed function: PlayStretchedButton
        function PlayStretchedButtonPushed(app, event)
            soundsc(app.y_prop,44100)
        end

        % Button pushed function: Stop_SoundButton
        function Stop_SoundButtonPushed(app, event)
            clear sound;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {555, 555};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {251, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: VariableQSwitch
        function VariableQSwitchValueChanged(app, event)
            value = app.VariableQSwitch.Value;
            switch value
                    case 'On'
                     app.lamp_prop = 1;                     
                     case 'Off'
                    app.lamp_prop = 0;
            
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Phase Vocoder';
            app.ComputeButton = uibutton(app.LeftPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.OverlapKnobLabel = uilabel(app.LeftPanel);
            app.OverlapKnobLabel.Text = 'Overlap';
            app.OverlapKnob = uiknob(app.LeftPanel, 'continuous');
            app.OverlapKnob.Limits = [0 80];
            app.OverlapKnob.Value = 75;
            app.WindowSliderLabel = uilabel(app.LeftPanel);
            app.WindowSliderLabel.Text = 'Window';
            app.WindowSlider = uislider(app.LeftPanel);
            app.WindowSlider.Limits = [10 100];
            app.WindowSlider.Value = 50;
            app.StretchQKnobLabel = uilabel(app.LeftPanel);
            app.StretchQKnobLabel.Text = 'Stretch(Q)';
            app.StretchQKnob = uiknob(app.LeftPanel, 'continuous');
            app.StretchQKnob.Limits = [0 3];
            app.StretchQKnob.Value = 1;
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileNameEditFieldLabel = uilabel(app.LeftPanel);
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.FileNameEditField = uieditfield(app.LeftPanel, 'text');
            app.FileNameEditField.Value = 'piano.wav';
            app.VariableQSwitchLabel = uilabel(app.LeftPanel);
            app.VariableQSwitchLabel.Text = 'VariableQ';
            app.VariableQSwitch = uiswitch(app.LeftPanel, 'slider');
            app.VariableQSwitch.ValueChangedFcn = createCallbackFcn(app, @VariableQSwitchValueChanged, true);
            app.PitchKnobLabel = uilabel(app.LeftPanel);
            app.PitchKnobLabel.Text = 'Pitch';
            app.PitchKnob = uiknob(app.LeftPanel, 'discrete');
            app.PitchKnob.Items = {'0.5', '0.75', '1', '1.5', '2'};
            app.PitchKnob.Value = '1';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.PlayOriginalButton = uibutton(app.RightPanel, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.PlayStretchedButton = uibutton(app.RightPanel, 'push');
            app.PlayStretchedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayStretchedButtonPushed, true);
            app.PlayStretchedButton.Text = 'Play Stretched';
            app.UIAxes5 = uiaxes(app.RightPanel);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes6 = uiaxes(app.RightPanel);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.Stop_SoundButton = uibutton(app.RightPanel, 'push');
            app.Stop_SoundButton.ButtonPushedFcn = createCallbackFcn(app, @Stop_SoundButtonPushed, true);
            app.Stop_SoundButton.Text = 'Stop_Sound';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.StatusLabel = uilabel(app.RightPanel);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Phase_Vocoder_GUI_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end